classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DokowanieciarwekAlogrytmGenetycznyUIFigure  matlab.ui.Figure
        InstrukcjaMenu                 matlab.ui.container.Menu
        MetodaButtonGroup              matlab.ui.container.ButtonGroup
        RankingowaButton               matlab.ui.control.RadioButton
        TurniejowaButton               matlab.ui.control.RadioButton
        DodajwasnyscenariuszPanel      matlab.ui.container.Panel
        PodgldButton                   matlab.ui.control.Button
        WarunkipocztkowePanel          matlab.ui.container.Panel
        teta_tEditField                matlab.ui.control.NumericEditField
        teta_tEditFieldLabel           matlab.ui.control.Label
        teta_cEditField                matlab.ui.control.NumericEditField
        teta_cEditFieldLabel           matlab.ui.control.Label
        OrientacjaradLabel             matlab.ui.control.Label
        PunktstartowyLabel             matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        DodajpunktkolizjiPanel         matlab.ui.container.Panel
        UsuButton                      matlab.ui.control.Button
        DodajButton                    matlab.ui.control.Button
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        ObiektSpinner                  matlab.ui.control.Spinner
        ObiektSpinnerLabel             matlab.ui.control.Label
        KrawdEditField                 matlab.ui.control.NumericEditField
        KrawdEditFieldLabel            matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        UruchomButton                  matlab.ui.control.Button
        ScenariuszDropDown             matlab.ui.control.DropDown
        ScenariuszDropDownLabel        matlab.ui.control.Label
        WielkopopulacjiEditField       matlab.ui.control.NumericEditField
        WielkopopulacjiEditFieldLabel  matlab.ui.control.Label
        MaksymalnaliczbapokoleEditField  matlab.ui.control.NumericEditField
        MaksymalnaliczbapokoleEditFieldLabel  matlab.ui.control.Label
        PrawdopodobietwomutacjiEditField  matlab.ui.control.NumericEditField
        PrawdopodobietwomutacjiEditFieldLabel  matlab.ui.control.Label
        PrawdopodobietwokrzyowaniaEditField  matlab.ui.control.NumericEditField
        PrawdopodobietwokrzyowaniaEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        i = 1; % nr punktu w obiekcie
        granica = 50;

        x0 = 35;
        y0 = 33;

        teta_t = 0;
        teta_c = 0;

        PSC = zeros(2,2,2);
    end

    properties (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {'-','-','-','-','-';'-','-','-','-','-';'-','-','-','-','-'};
        end

        % Button pushed function: UruchomButton
        function UruchomButtonPushed(app, event)
            % jeśli wciśnięto uruchom czytaj parametry i uruchom
            % odpowiedni program
            app.UruchomButton.Enable ='off'; % aby nie można było kliknąć wielokrotnie
            pk = app.PrawdopodobietwokrzyowaniaEditField.Value;
            pm = app.PrawdopodobietwomutacjiEditField.Value;
            M = app.WielkopopulacjiEditField.Value;
            MAX_Pokolen = app.MaksymalnaliczbapokoleEditField.Value;
            sce = app.ScenariuszDropDown.Value;
            switch sce
                case '1'
                    opcja = 1;
                case '2'
                    opcja = 2;
                case '3'
                    opcja = 3;
                case '4'
                    opcja = 4;
                case '5'
                    opcja = 5;
                case '6'
                    opcja = 6;
                case '7'
                    opcja = 7;
                case '8'
                    opcja = 8;
                case '9'
                    opcja = 9;
                case '10'
                    opcja = 10;
                case '11'
                    opcja = 11;
                case '12'
                    opcja = 12;
                otherwise
                    opcja = 0;
            end
            if(app.RankingowaButton.Value == true)
                algorytm_rank(pk ,pm ,M ,MAX_Pokolen, opcja, app.granica, app.x0, app.y0, app.teta_t, app.teta_c, 0, app.PSC);
            else
                algorytm_tur(pk ,pm ,M ,MAX_Pokolen, opcja, app.granica, app.x0, app.y0, app.teta_t, app.teta_c, 0, app.PSC);
            end
            app.UruchomButton.Enable ='on';
        end

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
            if(app.i < 6) % max 5 punktów w każdym obiekcie
                app.granica = app.KrawdEditField.Value;
                xP = app.xEditField.Value;
                yP = app.yEditField.Value;
                app.teta_c = app.teta_cEditField.Value;
                app.teta_t = app.teta_tEditField.Value;
                grupa = app.ObiektSpinner.Value;
                app.x0 = app.xEditField_2.Value;
                app.y0 = app.yEditField_2.Value;
                app.PSC(grupa,app.i ,1) = xP;
                app.PSC(grupa,app.i ,2) = yP;
                formatSpec = '%.1f';
                app.UITable.Data{app.i,grupa} = append(num2str(xP,formatSpec),', ',num2str(yP,formatSpec));
                app.i  = app.i  + 1;
            end
        end

        % Value changed function: ObiektSpinner
        function ObiektSpinnerValueChanged(app, event)
            app.i = 1;
        end

        % Button pushed function: UsuButton
        function UsuButtonPushed(app, event)
            % usuwa wszystkie punkty
            if(app.i > 1)
                clear app.PSC;
                app.UITable.Data = {'-','-','-','-','-';'-','-','-','-','-';'-','-','-','-','-'};
                app.i = 1;
            end
        end

        % Button pushed function: PodgldButton
        function PodgldButtonPushed(app, event)
            app.granica = app.KrawdEditField.Value;
            xP = app.xEditField.Value;
            yP = app.yEditField.Value;
            app.teta_c = app.teta_cEditField.Value;
            app.teta_t = app.teta_tEditField.Value;
            grupa = app.ObiektSpinner.Value;
            app.x0 = app.xEditField_2.Value;
            app.y0 = app.yEditField_2.Value;
            app.PSC(grupa,app.i ,1) = xP;
            app.PSC(grupa,app.i ,2) = yP;
            podglad(app.PSC,app.granica,app.x0,app.y0,app.teta_t,app.teta_c);
        end

        % Menu selected function: InstrukcjaMenu
        function InstrukcjaMenuSelected(app, event)
            Instrukcja;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DokowanieciarwekAlogrytmGenetycznyUIFigure = uifigure('Visible', 'off');
            app.DokowanieciarwekAlogrytmGenetycznyUIFigure.Name = 'Dokowanie ciężarówek - Alogrytm Genetyczny';
            app.InstrukcjaMenu = uimenu(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.InstrukcjaMenu.MenuSelectedFcn = createCallbackFcn(app, @InstrukcjaMenuSelected, true);
            app.InstrukcjaMenu.Text = 'Instrukcja';
            app.PrawdopodobietwokrzyowaniaEditFieldLabel = uilabel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.PrawdopodobietwokrzyowaniaEditFieldLabel.Text = 'Prawdopodobieńśtwo krzyżowania';
            app.PrawdopodobietwokrzyowaniaEditField = uieditfield(app.DokowanieciarwekAlogrytmGenetycznyUIFigure, 'numeric');
            app.PrawdopodobietwokrzyowaniaEditField.Limits = [0 1];
            app.PrawdopodobietwokrzyowaniaEditField.ValueDisplayFormat = '%.2f';
            app.PrawdopodobietwokrzyowaniaEditField.Value = 0.95;
            app.PrawdopodobietwomutacjiEditFieldLabel = uilabel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.PrawdopodobietwomutacjiEditFieldLabel.Text = 'Prawdopodobieńśtwo mutacji';
            app.PrawdopodobietwomutacjiEditField = uieditfield(app.DokowanieciarwekAlogrytmGenetycznyUIFigure, 'numeric');
            app.PrawdopodobietwomutacjiEditField.Limits = [0 1];
            app.PrawdopodobietwomutacjiEditField.ValueDisplayFormat = '%.2f';
            app.PrawdopodobietwomutacjiEditField.Value = 0.01;
            app.MaksymalnaliczbapokoleEditFieldLabel = uilabel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.MaksymalnaliczbapokoleEditFieldLabel.Text = 'Maksymalna liczba pokoleń';
            app.MaksymalnaliczbapokoleEditField = uieditfield(app.DokowanieciarwekAlogrytmGenetycznyUIFigure, 'numeric');
            app.MaksymalnaliczbapokoleEditField.Limits = [1 400];
            app.MaksymalnaliczbapokoleEditField.ValueDisplayFormat = '%.0f';
            app.MaksymalnaliczbapokoleEditField.Value = 60;
            app.WielkopopulacjiEditFieldLabel = uilabel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.WielkopopulacjiEditFieldLabel.Text = 'Wielkość populacji';
            app.WielkopopulacjiEditField = uieditfield(app.DokowanieciarwekAlogrytmGenetycznyUIFigure, 'numeric');
            app.WielkopopulacjiEditField.Limits = [2 5000];
            app.WielkopopulacjiEditField.ValueDisplayFormat = '%.0f';
            app.WielkopopulacjiEditField.Value = 800;
            app.ScenariuszDropDownLabel = uilabel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.ScenariuszDropDownLabel.Text = 'Scenariusz';
            app.ScenariuszDropDown = uidropdown(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.ScenariuszDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', 'Własny'};
            app.ScenariuszDropDown.Value = '1';
            app.UruchomButton = uibutton(app.DokowanieciarwekAlogrytmGenetycznyUIFigure, 'push');
            app.UruchomButton.ButtonPushedFcn = createCallbackFcn(app, @UruchomButtonPushed, true);
            app.UruchomButton.Text = 'Uruchom';
            app.UITable = uitable(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.UITable.ColumnName = {'Obiekt 1'; 'Obiekt 2'; 'Obiekt 3'; 'Obiekt 4'; 'Obiekt 5'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DodajwasnyscenariuszPanel = uipanel(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.DodajwasnyscenariuszPanel.Title = 'Dodaj własny scenariusz';
            app.KrawdEditFieldLabel = uilabel(app.DodajwasnyscenariuszPanel);
            app.KrawdEditFieldLabel.Text = 'Krawędź';
            app.KrawdEditField = uieditfield(app.DodajwasnyscenariuszPanel, 'numeric');
            app.KrawdEditField.Limits = [30 70];
            app.KrawdEditField.ValueDisplayFormat = '%.0f';
            app.KrawdEditField.Value = 60;
            app.DodajpunktkolizjiPanel = uipanel(app.DodajwasnyscenariuszPanel);
            app.DodajpunktkolizjiPanel.Title = 'Dodaj punkt kolizji';
            app.ObiektSpinnerLabel = uilabel(app.DodajpunktkolizjiPanel);
            app.ObiektSpinnerLabel.Text = 'Obiekt';
            app.ObiektSpinner = uispinner(app.DodajpunktkolizjiPanel);
            app.ObiektSpinner.Limits = [1 5];
            app.ObiektSpinner.ValueDisplayFormat = '%.0f';
            app.ObiektSpinner.ValueChangedFcn = createCallbackFcn(app, @ObiektSpinnerValueChanged, true);
            app.ObiektSpinner.Value = 1;
            app.xEditFieldLabel = uilabel(app.DodajpunktkolizjiPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DodajpunktkolizjiPanel, 'numeric');
            app.xEditField.Limits = [0 100];
            app.xEditField.ValueDisplayFormat = '%.1f';
            app.xEditField.Value = 5;
            app.yEditFieldLabel = uilabel(app.DodajpunktkolizjiPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DodajpunktkolizjiPanel, 'numeric');
            app.yEditField.Limits = [0 100];
            app.yEditField.ValueDisplayFormat = '%.1f';
            app.yEditField.Value = 5;
            app.DodajButton = uibutton(app.DodajpunktkolizjiPanel, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.UsuButton = uibutton(app.DodajpunktkolizjiPanel, 'push');
            app.UsuButton.ButtonPushedFcn = createCallbackFcn(app, @UsuButtonPushed, true);
            app.UsuButton.Text = 'Usuń';
            app.WarunkipocztkowePanel = uipanel(app.DodajwasnyscenariuszPanel);
            app.WarunkipocztkowePanel.Title = 'Warunki początkowe';
            app.xEditField_2Label = uilabel(app.WarunkipocztkowePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.WarunkipocztkowePanel, 'numeric');
            app.xEditField_2.Limits = [5 95];
            app.xEditField_2.ValueDisplayFormat = '%.1f';
            app.xEditField_2.Value = 5;
            app.yEditField_2Label = uilabel(app.WarunkipocztkowePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.WarunkipocztkowePanel, 'numeric');
            app.yEditField_2.Limits = [5 95];
            app.yEditField_2.ValueDisplayFormat = '%.1f';
            app.yEditField_2.Value = 5;
            app.PunktstartowyLabel = uilabel(app.WarunkipocztkowePanel);
            app.PunktstartowyLabel.Text = 'Punkt startowy:';
            app.OrientacjaradLabel = uilabel(app.WarunkipocztkowePanel);
            app.OrientacjaradLabel.Text = 'Orientacja (rad):';
            app.teta_cEditFieldLabel = uilabel(app.WarunkipocztkowePanel);
            app.teta_cEditFieldLabel.Text = 'teta_c';
            app.teta_cEditField = uieditfield(app.WarunkipocztkowePanel, 'numeric');
            app.teta_cEditField.Limits = [0 6.28318530717959];
            app.teta_tEditFieldLabel = uilabel(app.WarunkipocztkowePanel);
            app.teta_tEditFieldLabel.Text = 'teta_t';
            app.teta_tEditField = uieditfield(app.WarunkipocztkowePanel, 'numeric');
            app.teta_tEditField.Limits = [0 6.2832];
            app.PodgldButton = uibutton(app.DodajwasnyscenariuszPanel, 'push');
            app.PodgldButton.ButtonPushedFcn = createCallbackFcn(app, @PodgldButtonPushed, true);
            app.PodgldButton.Text = 'Podgląd';
            app.MetodaButtonGroup = uibuttongroup(app.DokowanieciarwekAlogrytmGenetycznyUIFigure);
            app.MetodaButtonGroup.Title = 'Metoda:';
            app.TurniejowaButton = uiradiobutton(app.MetodaButtonGroup);
            app.TurniejowaButton.Text = 'Turniejowa';
            app.TurniejowaButton.Value = true;
            app.RankingowaButton = uiradiobutton(app.MetodaButtonGroup);
            app.RankingowaButton.Text = 'Rankingowa';
            % Show the figure after all components are created
            app.DokowanieciarwekAlogrytmGenetycznyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DokowanieciarwekAlogrytmGenetycznyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DokowanieciarwekAlogrytmGenetycznyUIFigure)
        end
    end
end